classdef WiFi_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensEduUltrasonicRangingWiFiGUIUIFigure  matlab.ui.Figure
        PlottingModeButtonGroup  matlab.ui.container.ButtonGroup
        DistanceButton           matlab.ui.control.ToggleButton
        XCORRButton_2            matlab.ui.control.ToggleButton
        FilteredButton_2         matlab.ui.control.ToggleButton
        RawButton_2              matlab.ui.control.ToggleButton
        DataTransmissionModeButtonGroup  matlab.ui.container.ButtonGroup
        DetailedButton           matlab.ui.control.RadioButton
        SimpleButton             matlab.ui.control.RadioButton
        ShotButton               matlab.ui.control.Button
        IPAddressEditField       matlab.ui.control.EditField
        IPAddressEditFieldLabel  matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    %%settings live    
    data;

    %%setings detailed
    ITERATIONS = 25; 
    MIC_NUM = 1;
    MIC_NAMES = {"MIC 1"};
    DATA_LENGTH = 32 * 32;
    PROCESSING_STEPS = 3; % raw, fitlered, xcorr

    stop = 0;
    end
    
    properties (Access = private)
        RawLine matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty
    end
    
    
    methods (Access = private)

    function startupFcn(app)
        app.data = zeros(1, app.ITERATIONS);
        % Prepare axes for Raw data
        cla(app.UIAxes);
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, "Sample #");
        ylabel(app.UIAxes, "ADC 16bit value");
        title(app.UIAxes, "Raw ADC Data");
    end
    
    function data = read_data(app, arduino_server, data_length)
        total_byte_length = data_length * 2; % 2 bytes per sample
        serial_rx_data = read(arduino_server, total_byte_length, 'uint8');
        data = double(typecast(uint8(serial_rx_data), 'uint16'));
    end

    function data = read_16bit_data(app, arduino_server, data_length)
        raw_data = read(arduino_server, data_length * 2, 'uint8');
        data = typecast_uint8_uint16(app, raw_data);
    end
    
    function data = read_float_data(app, arduino_server, data_length)
        raw_data = read(arduino_server, data_length * 4, 'uint8');
        data = typecast_uint8_float(app, raw_data);
    end

    function dist_vector = read_distance_data(app, arduino_server, mic_num)
        dist_vector = zeros(mic_num, 1);
        for i = 1:mic_num
            serial_rx_data = read(arduino_server, 4, 'uint8'); % 32bit per one distance measurement
            dist_vector(i, 1) = double(typecast(uint8(serial_rx_data), 'uint32'))/1e6; % expected in micrometers
        end
    end
    
    function casted_data = typecast_uint8_uint16(app, data)
        reshaped_data = reshape(data, 2, []);
        casted_data = bitshift(uint16(reshaped_data(2, :)), 8) + uint16(reshaped_data(1, :));
    end

    function casted_data = typecast_uint8_float(app, data)
        reshaped_data = reshape(data, 4, []);
        casted_data = uint32(reshaped_data(1, :));
        for i = 2:4
            casted_data = casted_data + bitshift(uint32(reshaped_data(i, :)), 8*(i-1));
        end
        casted_data = typecast(casted_data, 'single');
    end

    function plot_data(app, data)
        if isempty(app.RawLine) || ~isvalid(app.RawLine)
            app.RawLine = plot(app.UIAxes, 1:numel(data), double(data), 'LineWidth', 1.5);
            xlim(app.UIAxes, [1, numel(data)]);
            ylim(app.UIAxes, [0, 65535]);
        else
            set(app.RawLine, 'XData', 1:numel(data), 'YData', double(data));
        end
        drawnow limitrate
    end
    
    
    % function plot_data(app, data)
    %     plot(app.UIAxes, data);
    %     ylim(app.UIAxes, [0, 65535]);
    %     xlabel(app.UIAxes, "Sample #");
    %     ylabel(app.UIAxes, "ADC 16bit value");
    %     grid(app.UIAxes, 'on');
    % end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Start live Plotting
            % DATA_LENGTH = 16*128;
            % 
            % ip = app.IPAddressEditField.Value;
            % arduino_server = tcpclient(ip,80,"Timeout",50,"ConnectTimeout",50);
            % 
            % 
            % time_axis = zeros(1,10000);
            % 
            % for it = 1:10000
            %     if app.stop == 1
            %         app.stop = 0;
            %         break;
            %     end
            %     % Data readings
            %     write(arduino_server, 't', "char"); % trigger arduino measurement
            %     time_axis(it) = toc;
            %     tic
            %     app.data = app.read_data(arduino_server, DATA_LENGTH);
            %     toc
            %     app.plot_data(app.data);
            
            ip = app.IPAddressEditField.Value;

            arduino_server = tcpclient(ip, 80, "Timeout", 10, "ConnectTimeout", 10);
            % Optional: store for Stop to clean up
            %cleanupObj = onCleanup(@() clear app.arduino_server);

            for it = 1:10000
                if app.stop == 1
                    app.stop = 0;
                    break;
                end
                write(arduino_server, 't', "char"); % trigger device
                data1 = app.read_16bit_data(arduino_server, app.DATA_LENGTH);
                data2 = app.read_float_data(arduino_server, app.DATA_LENGTH);
                data3 = app.read_float_data(arduino_server, app.DATA_LENGTH);
                data4 = app.read_distance_data(arduino_server, app.MIC_NUM);
                app.data = data1; % uint16 Raw
                app.plot_data(app.data); % Raw-only plotting
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = 1;
        end

        % Button pushed function: ShotButton
        function ShotButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensEduUltrasonicRangingWiFiGUIUIFigure = uifigure('Visible', 'off');
            app.SensEduUltrasonicRangingWiFiGUIUIFigure.Name = 'SensEdu Ultrasonic Ranging WiFi GUI';
            app.UIAxes = uiaxes(app.SensEduUltrasonicRangingWiFiGUIUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.SensEduUltrasonicRangingWiFiGUIUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SensEduUltrasonicRangingWiFiGUIUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IPAddressEditFieldLabel = uilabel(app.SensEduUltrasonicRangingWiFiGUIUIFigure);
            app.IPAddressEditFieldLabel.Text = 'IPAddress';
            app.IPAddressEditField = uieditfield(app.SensEduUltrasonicRangingWiFiGUIUIFigure, 'text');
            app.IPAddressEditField.Value = '192.168.56.128';
            app.ShotButton = uibutton(app.SensEduUltrasonicRangingWiFiGUIUIFigure, 'push');
            app.ShotButton.ButtonPushedFcn = createCallbackFcn(app, @ShotButtonPushed, true);
            app.ShotButton.Text = 'Shot';
            app.DataTransmissionModeButtonGroup = uibuttongroup(app.SensEduUltrasonicRangingWiFiGUIUIFigure);
            app.DataTransmissionModeButtonGroup.Title = 'Data Transmission Mode';
            app.SimpleButton = uiradiobutton(app.DataTransmissionModeButtonGroup);
            app.SimpleButton.Text = 'Simple';
            app.SimpleButton.Value = true;
            app.DetailedButton = uiradiobutton(app.DataTransmissionModeButtonGroup);
            app.DetailedButton.Text = 'Detailed';
            app.PlottingModeButtonGroup = uibuttongroup(app.SensEduUltrasonicRangingWiFiGUIUIFigure);
            app.PlottingModeButtonGroup.Title = 'Plotting Mode';
            app.RawButton_2 = uitogglebutton(app.PlottingModeButtonGroup);
            app.RawButton_2.Text = 'Raw';
            app.RawButton_2.Value = true;
            app.FilteredButton_2 = uitogglebutton(app.PlottingModeButtonGroup);
            app.FilteredButton_2.Text = 'Filtered';
            app.XCORRButton_2 = uitogglebutton(app.PlottingModeButtonGroup);
            app.XCORRButton_2.Text = 'XCORR';
            app.DistanceButton = uitogglebutton(app.PlottingModeButtonGroup);
            app.DistanceButton.Text = 'Distance';
            % Show the figure after all components are created
            app.SensEduUltrasonicRangingWiFiGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WiFi_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SensEduUltrasonicRangingWiFiGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensEduUltrasonicRangingWiFiGUIUIFigure)
        end
    end
end